classdef Configuracao_rede < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        ConfiguraodaRedePanel      matlab.ui.container.Panel
        Avancar                    matlab.ui.control.Button
        FunodeAtivaoDropDownLabel  matlab.ui.control.Label
        FunodeAtivaoDropDown       matlab.ui.control.DropDown
        FunodeTreinoDropDownLabel  matlab.ui.control.Label
        FunodeTreinoDropDown       matlab.ui.control.DropDown
        RedesLabel                 matlab.ui.control.Label
        RedesDropDown              matlab.ui.control.DropDown
        DivisoparaTreinoTesteValidaoPanel  matlab.ui.container.Panel
        TreinoEditFieldLabel       matlab.ui.control.Label
        TreinoEditField            matlab.ui.control.NumericEditField
        TesteLabel                 matlab.ui.control.Label
        TesteEditField             matlab.ui.control.NumericEditField
        ValidaoEditFieldLabel      matlab.ui.control.Label
        ValidaoEditField           matlab.ui.control.NumericEditField
        NeurniosSpinnerLabel       matlab.ui.control.Label
        NeurniosSpinner            matlab.ui.control.Spinner
        pocasSpinnerLabel          matlab.ui.control.Label
        pocasSpinner               matlab.ui.control.Spinner
        NotaTextArea               matlab.ui.control.TextArea
    end


%     methods
%         
%          
%         function value=PropertyGetMethod(app)
%             value=app.Property;
%         end
%         
%         function value=Property2GetMethod(app)
%             value=app.Property2;
%         end
%          
%         function value=Property3GetMethod(app)
%             value=app.Property3;
%         end
%         function value=Property4GetMethod(app)
%             value=app.Property4;
%         end
%      
%         function value=Property5GetMethod(app)
%             value=app.Property5;
%         end
%       
%         
%         
%         function value=get.Property(app)
%             value=app.Property;
%         end
%         function value=get.Property2(app)
%             value=app.Property2;
%         end
%         function value=get.Property3(app)
%             value=app.Property3;
%         end
%         function value=get.Property4(app)
%             value=app.Property4;
%         end
%         function value=get.Property5(app)
%             value=app.Property5;
%         end
%         
%         function app=PropertySetMethod(app,value)
%             if nargin>0
%                 app.Property=value;
%             end
%         end
%         function app=Property2SetMethod(app,value)
%             if nargin>0
%                 app.Property2=value;
%             end
%         end
%         function app=Property3SetMethod(app,value)
%             if nargin>0
%                 app.Property3=value;
%             end
%         end
%         function app=Property4SetMethod(app,value)
%             if nargin>0
%                 app.Property4=value;
%             end
%         end
%         function app=Property5SetMethod(app,value)
%             if nargin>0
%                 app.Property5=value;
%             end
%         end
%         
%         
%         
%         function app = set.Property(app,value)
%             if(value>0)
%                 app.Property=value;
%             else
%                 error('Property value must be positive')
%             end
%             
%         end
%         function app = set.Property2(app,value)
%             if(value>0)
%                 app.Property2=value;
%             else
%                 error('Property value must be positive')
%             end
%             
%         end
%         function app = set.Property3(app,value)
%             if(value>0)
%                 app.Property3=value;
%             else
%                 error('Property value must be positive')
%             end
%             
%         end
%         function app = set.Property4(app,value)
%             if(value>0)
%                 app.Property4=value;
%             else
%                 error('Property value must be positive')
%             end
%             
%         end
%         function app = set.Property5(app,value)
%             if(value>0)
%                 app.Property5=value;
%             else
%                 error('Property value must be positive')
%             end
%             
%         end
%     end
%     
%     


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Avancar
        function AvancarPushed(app, event)
             T4=Selecionar_pasta_para_treino;
            ativacao = app.FunodeAtivaoDropDown.Value;
         
          T4.Property1=ativacao;
            treino = app.FunodeTreinoDropDown.Value;
           
          T4.Property2=treino;
            redesN = app.RedesDropDown.Value;
           
          T4.Property3=redesN;
          epocas = app.pocasSpinner.Value;
          
          T4.Property4=epocas;
          neuronios = app.NeurniosSpinner.Value;
             
          T4.Property5=neuronios;
          
          treinoValue=app.TreinoEditField.Value;
          
          T4.Property6=treinoValue;
          
          teste=app.TesteEditField.Value;
          T4.Property7=teste;
          
          validacao=app.ValidaoEditField.Value;
          T4.Property8=validacao;


        end

        % Callback function
        function NumerodeEpocasButtonGroupSelectionChanged(app, event)
            
            
        end

        % Callback function
        function FunaodeTreinoButtonGroupSelectionChanged(app, event)
            
            
        end

        % Callback function
        function FuncaodeAtivacaoButtonGroupSelectionChanged(app, event)


        end

        % Callback function
        function NumerodeNeuroniosButtonGroupSelectionChanged(app, event)
           
           
        end

        % Callback function
        function RedesButtonGroupSelectionChanged(app, event)


        end

        % Callback function
        function FuncaoTreinoValueChanged(app, event)
            
            
        end

        % Callback function
        function RedesValueChanged(app, event)
            
            
        end

        % Callback function
        function EpocasValueChanged(app, event)
            
            
        end

        % Callback function
        function NeuroniosValueChanged(app, event)
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ConfiguraodaRedePanel = uipanel(app.UIFigure);
            app.ConfiguraodaRedePanel.Title = 'Configuração da Rede';
            app.Avancar = uibutton(app.ConfiguraodaRedePanel, 'push');
            app.Avancar.ButtonPushedFcn = createCallbackFcn(app, @AvancarPushed, true);
            app.Avancar.Text = 'Avançar';
            app.FunodeAtivaoDropDownLabel = uilabel(app.ConfiguraodaRedePanel);
            app.FunodeAtivaoDropDownLabel.Text = 'Função de Ativação:';
            app.FunodeAtivaoDropDown = uidropdown(app.ConfiguraodaRedePanel);
            app.FunodeAtivaoDropDown.Items = {'logsig', 'purelin', 'tansig'};
            app.FunodeAtivaoDropDown.Value = 'logsig';
            app.FunodeTreinoDropDownLabel = uilabel(app.ConfiguraodaRedePanel);
            app.FunodeTreinoDropDownLabel.Text = 'Função de Treino:';
            app.FunodeTreinoDropDown = uidropdown(app.ConfiguraodaRedePanel);
            app.FunodeTreinoDropDown.Items = {'trainbfg', 'traingd', 'trainlm'};
            app.FunodeTreinoDropDown.Value = 'trainbfg';
            app.RedesLabel = uilabel(app.ConfiguraodaRedePanel);
            app.RedesLabel.Text = 'Redes:';
            app.RedesDropDown = uidropdown(app.ConfiguraodaRedePanel);
            app.RedesDropDown.Items = {'feedforwardnet', 'patternnet'};
            app.RedesDropDown.Value = 'feedforwardnet';
            app.DivisoparaTreinoTesteValidaoPanel = uipanel(app.ConfiguraodaRedePanel);
            app.DivisoparaTreinoTesteValidaoPanel.Title = 'Divisão para Treino,Teste,Validação';
            app.TreinoEditFieldLabel = uilabel(app.DivisoparaTreinoTesteValidaoPanel);
            app.TreinoEditFieldLabel.Text = 'Treino:';
            app.TreinoEditField = uieditfield(app.DivisoparaTreinoTesteValidaoPanel, 'numeric');
            app.TesteLabel = uilabel(app.DivisoparaTreinoTesteValidaoPanel);
            app.TesteLabel.Text = 'Teste:';
            app.TesteEditField = uieditfield(app.DivisoparaTreinoTesteValidaoPanel, 'numeric');
            app.ValidaoEditFieldLabel = uilabel(app.DivisoparaTreinoTesteValidaoPanel);
            app.ValidaoEditFieldLabel.Text = 'Validação:';
            app.ValidaoEditField = uieditfield(app.DivisoparaTreinoTesteValidaoPanel, 'numeric');
            app.NeurniosSpinnerLabel = uilabel(app.ConfiguraodaRedePanel);
            app.NeurniosSpinnerLabel.Text = 'Neurónios:';
            app.NeurniosSpinner = uispinner(app.ConfiguraodaRedePanel);
            app.pocasSpinnerLabel = uilabel(app.ConfiguraodaRedePanel);
            app.pocasSpinnerLabel.Text = 'Épocas:';
            app.pocasSpinner = uispinner(app.ConfiguraodaRedePanel);
            app.NotaTextArea = uitextarea(app.ConfiguraodaRedePanel);
            app.NotaTextArea.Value = {'Nota: Se for a alínea A) como todos os exemplos são usados, não é preciso inserir os valores do quadro em baixo'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Configuracao_rede

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end