classdef JanelaResultados < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UITable                         matlab.ui.control.Table
        PreenchercomosresultadosButton  matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
        UIAxes2                         matlab.ui.control.UIAxes
        ResultadosLabel                 matlab.ui.control.Label
        SalvarredeneuronalButton        matlab.ui.control.Button
        SalvarficheirocomresultadosButton  matlab.ui.control.Button
        UIAxes2_2                       matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        Property9
        Property10
        Property11
        Property12
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            
        end

        % Button pushed function: PreenchercomosresultadosButton
        function PreenchercomosresultadosButtonPushed(app, event)
            fig = figure('Name','Numbers');
            t = uitable(fig,'Data',{app.Property11,app.Property12});
            t.ColumnName = {'Train Score','Test Score'};
            app.UITable.Data={app.Property11,app.Property12};
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.Position=[20 80 500 500];
            img='Matriz_Confusao.jpg'
            imshow(img,'Parent',app.UIAxes);
            app.UIAxes2 = uiaxes(app.UIFigure);
            app.UIAxes2.Position=[500 130 500 500];
            fig='Desempenho.png';
            imshow(fig,'Parent',app.UIAxes2);
            
        end

        % Button pushed function: SalvarredeneuronalButton
        function SalvarredeneuronalButtonPushed(app, event)
            net=app.Property10;
            name = inputdlg('Introduza o nome da rede:', 'Gravar rede');
            
            fname = sprintf('%s.mat', name{1});
            
            save (fname,'net')  % EDITED
            
        end

        % Button pushed function: SalvarficheirocomresultadosButton
        function SalvarficheirocomresultadosButtonPushed(app, event)
            name = inputdlg('Introduza o nome do ficheiro:', 'Gravar Resultados');
            
            fname = sprintf('%s', name{1});
           saveas(gcf,fname,'pdf');


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Train Score'; 'Test Score'};
            app.UITable.RowName = {};
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.PreenchercomosresultadosButton = uibutton(app.UIFigure, 'push');
            app.PreenchercomosresultadosButton.ButtonPushedFcn = createCallbackFcn(app, @PreenchercomosresultadosButtonPushed, true);
            app.PreenchercomosresultadosButton.Text = 'Preencher com os resultados';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.Visible = 'off';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes2.Visible = 'off';
            app.ResultadosLabel = uilabel(app.UIFigure);
            app.ResultadosLabel.Text = 'Resultados: ';
            app.SalvarredeneuronalButton = uibutton(app.UIFigure, 'push');
            app.SalvarredeneuronalButton.ButtonPushedFcn = createCallbackFcn(app, @SalvarredeneuronalButtonPushed, true);
            app.SalvarredeneuronalButton.Text = 'Salvar rede neuronal';
            app.SalvarficheirocomresultadosButton = uibutton(app.UIFigure, 'push');
            app.SalvarficheirocomresultadosButton.ButtonPushedFcn = createCallbackFcn(app, @SalvarficheirocomresultadosButtonPushed, true);
            app.SalvarficheirocomresultadosButton.Text = 'Salvar ficheiro com resultados';
            app.UIAxes2_2 = uiaxes(app.UIFigure);
            title(app.UIAxes2_2, 'Title')
            xlabel(app.UIAxes2_2, 'X')
            ylabel(app.UIAxes2_2, 'Y')
            app.UIAxes2_2.Visible = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = JanelaResultados

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end