classdef Selecionar_pasta_para_treino < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        EscolherquepastadevogaistreinarPanel  matlab.ui.container.Panel
        EscolhapastaparatreinarDropDownLabel  matlab.ui.control.Label
        EscolhapastaparatreinarDropDown  matlab.ui.control.DropDown
        TreinarButton  matlab.ui.control.Button
    end

    
    properties (Access = public)
        Property1 %funcao_ativ
        Property2 %funcao_treino
        Property3 %redes
        Property4 %epocas
        Property5 %neuronios
        Property6 %treino
        Property7 %teste
        Property8 %validacao
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: TreinarButton
        function TreinarButtonPushed(app, event)
            value = app.EscolhapastaparatreinarDropDown.Value;
            fativacao=app.Property1;
            ftreino=app.Property2;
            redes= app.Property3;
            epocas=app.Property4;
            neuronios=app.Property5;
            treino=app.Property6;
            teste=app.Property7;
            validacao=app.Property8;
            R=JanelaResultados;
            if strcmp(value,"Pasta_1")==1
                [R.Property10,R.Property11]=alinea_A(redes,neuronios,fativacao,ftreino,epocas);
                R.Property12=0;
            else if strcmp(value,"Pasta_2")==1
                    [R.Property10,R.Property11,R.Property12]=alinea_B(redes,neuronios,fativacao,ftreino,epocas,treino,teste,validacao);
            else if strcmp(value, "Pasta_3") == 1
                    [R.Property10,R.Property11,R.Property12]=alinea_C_treino_pasta_1(redes,neuronios,fativacao,ftreino,epocas,treino,teste,validacao);            
            else if strcmp(value,"Todas_as_pastas")==1
                    [R.Property10,R.Property11,R.Property12]=alinea_C_treino_todas_pastas(redes,neuronios,fativacao,ftreino,epocas,treino,teste,validacao);
                    end
                end
            end        
        end

        % Value changed function: EscolhapastaparatreinarDropDown
        function EscolhapastaparatreinarDropDownValueChanged(app, event)
         value = app.EscolhapastaparatreinarDropDown.Value;
            
        end  
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.EscolherquepastadevogaistreinarPanel = uipanel(app.UIFigure);
            app.EscolherquepastadevogaistreinarPanel.Title = 'Escolher que pasta de vogais treinar';
            app.EscolhapastaparatreinarDropDownLabel = uilabel(app.EscolherquepastadevogaistreinarPanel);
            app.EscolhapastaparatreinarDropDownLabel.Text = 'Escolha pasta para treinar:';
            app.EscolhapastaparatreinarDropDown = uidropdown(app.EscolherquepastadevogaistreinarPanel);
            app.EscolhapastaparatreinarDropDown.Items = {'Pasta_1', 'Pasta_2', 'Pasta_3', 'Todas_as_pastas'};
            app.EscolhapastaparatreinarDropDown.ValueChangedFcn = createCallbackFcn(app, @EscolhapastaparatreinarDropDownValueChanged, true);
            app.EscolhapastaparatreinarDropDown.Value = 'Pasta_1';
            app.TreinarButton = uibutton(app.EscolherquepastadevogaistreinarPanel, 'push');
            app.TreinarButton.ButtonPushedFcn = createCallbackFcn(app, @TreinarButtonPushed, true);
            app.TreinarButton.Text = 'Treinar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Selecionar_pasta_para_treino

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end