classdef TreinoC < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        CarregarRedeButton           matlab.ui.control.Button
        TreinarRedeButton            matlab.ui.control.Button
        TextArea                     matlab.ui.control.TextArea
        SelecionepastaDropDownLabel  matlab.ui.control.Label
        SelecionepastaDropDown       matlab.ui.control.DropDown
    end


    properties (Access = public)
        Property18 % Description
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CarregarRedeButton
        function CarregarRedeButtonPushed(app, event)
            name = inputdlg('Introduza o nome da rede a usar:', 'Load da rede');
            
            fname = sprintf('%s.mat', name{1});
            app.Property18=fname;
        end

        % Button pushed function: TreinarRedeButton
        function TreinarRedeButtonPushed(app, event)
        R=JanelaResultados;
            value = app.SelecionepastaDropDown.Value;
            if strcmp(value,"Pasta 3")==1
                [R.Property10,R.Property11,R.Property12]=ponto_1(app.Property18);
            else if strcmp(value,"Todas as pastas")==1
                [R.Property10,R.Property11,R.Property12]=ponto_2(app.Property18);
            end
            end            
        end

        % Value changed function: SelecionepastaDropDown
        function SelecionepastaDropDownValueChanged(app, event)
            value = app.SelecionepastaDropDown.Value;           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.CarregarRedeButton = uibutton(app.UIFigure, 'push');
            app.CarregarRedeButton.ButtonPushedFcn = createCallbackFcn(app, @CarregarRedeButtonPushed, true);
            app.CarregarRedeButton.Text = 'Carregar Rede';
            app.TreinarRedeButton = uibutton(app.UIFigure, 'push');
            app.TreinarRedeButton.ButtonPushedFcn = createCallbackFcn(app, @TreinarRedeButtonPushed, true);
            app.TreinarRedeButton.Text = 'Treinar Rede';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'Treinar rede da pasta 3 ou de todas as pastas com a melhor rede da alinea B'};
            app.SelecionepastaDropDownLabel = uilabel(app.UIFigure);
            app.SelecionepastaDropDownLabel.Text = 'Selecione pasta ';
            app.SelecionepastaDropDown = uidropdown(app.UIFigure);
            app.SelecionepastaDropDown.Items = {'Pasta 3', 'Todas as pastas'};
            app.SelecionepastaDropDown.ValueChangedFcn = createCallbackFcn(app, @SelecionepastaDropDownValueChanged, true);
            app.SelecionepastaDropDown.Value = 'Pasta 3';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TreinoC

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end