classdef janelaLoadRede < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        CarregarRedeButton       matlab.ui.control.Button
        AplicarredeaLabel        matlab.ui.control.Label
        AplicarredeaDropDown     matlab.ui.control.DropDown
        ClassificarButton        matlab.ui.control.Button
        DesenharNovaVogalButton  matlab.ui.control.Button
        ClassificarLabel         matlab.ui.control.Label
    end

    
    properties (Access = public)
        Property13 % Description
        Property14
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CarregarRedeButton
        function CarregarRedeButtonPushed(app, event)
            name = inputdlg('Introduza o nome da rede a usar:', 'Load da rede');
            
            fname = sprintf('%s.mat', name{1});
            app.Property13=fname
            
        end

        % Value changed function: AplicarredeaDropDown
        function AplicarredeaDropDownValueChanged(app, event)
            
        end

        % Button pushed function: ClassificarButton
        function ClassificarButtonPushed(app, event)
            value = app.AplicarredeaDropDown.Value;
            if strcmp(value,"Vogais_Pasta_1")==1
                alinea_C_teste_pasta_1(app.Property13);
            else if strcmp(value,"Vogais_Pasta_2")==1
                alinea_C_teste_pasta_2(app.Property13);
            else if strcmp(value,"Vogais_Pasta_3")==1
                alinea_C(app.Property13);
            else if strcmp(value,"Formas_Desenhadas")==1
                    name = inputdlg('Introduza o nome da imagem a classificar:', 'Load da imagem',[1 35]);
                     fname = sprintf('%s.jpg', name{1});
                    R=ResultadoClassificacao;
                     [R.Property20]=tp_d(fname,app.Property13);
                    
                end
            end         
            end
            end
            
        end

        % Button pushed function: DesenharNovaVogalButton
        function DesenharNovaVogalButtonPushed(app, event)
            system('mspaint');
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.CarregarRedeButton = uibutton(app.UIFigure, 'push');
            app.CarregarRedeButton.ButtonPushedFcn = createCallbackFcn(app, @CarregarRedeButtonPushed, true);
            app.CarregarRedeButton.Text = 'Carregar Rede ';
            app.AplicarredeaLabel = uilabel(app.UIFigure);
            app.AplicarredeaLabel.Text = 'Aplicar rede a :';
            app.AplicarredeaDropDown = uidropdown(app.UIFigure);
            app.AplicarredeaDropDown.Items = {'Vogais_Pasta_1', 'Vogais_Pasta_2', 'Vogais_Pasta_3', 'Vogais_Desenhadas'};
            app.AplicarredeaDropDown.ValueChangedFcn = createCallbackFcn(app, @AplicarredeaDropDownValueChanged, true);
            app.AplicarredeaDropDown.Value = 'Vogais_Pasta_1';
            app.ClassificarButton = uibutton(app.UIFigure, 'push');
            app.ClassificarButton.ButtonPushedFcn = createCallbackFcn(app, @ClassificarButtonPushed, true);
            app.ClassificarButton.Text = 'Classificar';
            app.DesenharNovaVogalButton = uibutton(app.UIFigure, 'push');
            app.DesenharNovaVogalButton.ButtonPushedFcn = createCallbackFcn(app, @DesenharNovaVogalButtonPushed, true);
            app.DesenharNovaVogalButton.Text = 'Desenhar Nova Vogal';
            app.ClassificarLabel = uilabel(app.UIFigure);
            app.ClassificarLabel.Text = 'Classificar';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = janelaLoadRede

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end